classdef Receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        TextArea_demod      matlab.ui.control.TextArea
        Button_startRecord  matlab.ui.control.Button
        Button_stopRecord   matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        TextArea_time       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_startRecord
        function Button_startRecordPushed(app, event)
            global R;
            global t0;
            R = audiorecorder(48000, 24, 1);
            record(R);
            t0 = datetime;
        end

        % Button pushed function: Button_stopRecord
        function Button_stopRecordPushed(app, event)
            global R;
            global t0;
            stop(R);
            sig = getaudiodata(R);
            audiowrite('recv.wav', sig, 48000);
            [bits, t1] = FSK_demod('recv.wav', t0);     % 解调
            str = myDecode(bits);                       % 解码
            t2 = datetime;
            app.TextArea_demod.Value = str;
            dt = seconds(t2 - t1);                      % 传输时间
            app.TextArea_time.Value = num2str(dt);
            fileID = fopen('res.txt', 'w');
            fprintf(fileID, '%s\n', str);
            fprintf(fileID, '传输时间：%f(s)\n', dt);
            fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '解调结果';
            app.TextArea_demod = uitextarea(app.UIFigure);
            app.Button_startRecord = uibutton(app.UIFigure, 'push');
            app.Button_startRecord.ButtonPushedFcn = createCallbackFcn(app, @Button_startRecordPushed, true);
            app.Button_startRecord.Text = '开始录音';
            app.Button_stopRecord = uibutton(app.UIFigure, 'push');
            app.Button_stopRecord.ButtonPushedFcn = createCallbackFcn(app, @Button_stopRecordPushed, true);
            app.Button_stopRecord.Text = '停止录音并解调';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '传输时间';
            app.TextArea_time = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end