classdef A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_channel_on   matlab.ui.control.Button
        Button_emit         matlab.ui.control.Button
        Button_channel_off  matlab.ui.control.Button
        cmTextAreaLabel     matlab.ui.control.Label
        TextArea_distance   matlab.ui.control.TextArea
        sEditFieldLabel     matlab.ui.control.Label
        EditField_time      matlab.ui.control.EditField
        Button_distance     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_channel_on
        function Button_channel_onPushed(app, event)
            global fs;
            global R;
            global start_time;
            fs = 48000;
            R = audiorecorder(fs, 24, 1);
            record(R);
            start_time = datetime;
        end

        % Button pushed function: Button_emit
        function Button_emitPushed(app, event)
            global fs;
            global dt;
            global A_f0;
            global A_f1;
            dt = 0.5;
            A_f0 = 1000;
            A_f1 = 3000;
            emit(fs, dt, A_f0, A_f1);
        end

        % Button pushed function: Button_channel_off
        function Button_channel_offPushed(app, event)
            global R;
            global fs;
            stop(R);
            sig = getaudiodata(R);
            audiowrite("A.wav", sig, fs);
        end

        % Button pushed function: Button_distance
        function Button_distanceButtonPushed(app, event)
            global dt;
            global A_f0;
            global A_f1;
            global start_time;
            B_f0 = 4000;
            B_f1 = 6000;
            d = rangingCal('A.wav', 'B.wav', dt, A_f0, A_f1, B_f0, B_f1);
            app.TextArea_distance.Value = num2str(d);
            end_time = datetime;
            app.EditField_time.Value = num2str(seconds(end_time - start_time));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_channel_on = uibutton(app.UIFigure, 'push');
            app.Button_channel_on.ButtonPushedFcn = createCallbackFcn(app, @Button_channel_onPushed, true);
            app.Button_channel_on.Text = '开启信道';
            app.Button_emit = uibutton(app.UIFigure, 'push');
            app.Button_emit.ButtonPushedFcn = createCallbackFcn(app, @Button_emitPushed, true);
            app.Button_emit.Text = '发送信号';
            app.Button_channel_off = uibutton(app.UIFigure, 'push');
            app.Button_channel_off.ButtonPushedFcn = createCallbackFcn(app, @Button_channel_offPushed, true);
            app.Button_channel_off.Text = '关闭信道';
            app.cmTextAreaLabel = uilabel(app.UIFigure);
            app.cmTextAreaLabel.Text = '距离(cm)';
            app.TextArea_distance = uitextarea(app.UIFigure);
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = '测距耗时(s)';
            app.EditField_time = uieditfield(app.UIFigure, 'text');
            app.Button_distance = uibutton(app.UIFigure, 'push');
            app.Button_distance.ButtonPushedFcn = createCallbackFcn(app, @Button_distanceButtonPushed, true);
            app.Button_distance.Text = '计算距离';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end