classdef B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_channel_on   matlab.ui.control.Button
        Button_emit         matlab.ui.control.Button
        Button_channel_off  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_channel_on
        function Button_channel_onPushed(app, event)
            global fs;
            global R;
            fs = 48000;
            R = audiorecorder(fs, 24, 1);
            record(R);
        end

        % Button pushed function: Button_emit
        function Button_emitPushed(app, event)
            global fs;
            dt = 0.5;
            f0 = 4000;
            f1 = 6000;
            emit(fs, dt, f0, f1);
        end

        % Button pushed function: Button_channel_off
        function Button_channel_offPushed(app, event)
            global R;
            global fs;
            stop(R);
            sig = getaudiodata(R);
            audiowrite("B.wav", sig, fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_channel_on = uibutton(app.UIFigure, 'push');
            app.Button_channel_on.ButtonPushedFcn = createCallbackFcn(app, @Button_channel_onPushed, true);
            app.Button_channel_on.Text = '开启信道';
            app.Button_emit = uibutton(app.UIFigure, 'push');
            app.Button_emit.ButtonPushedFcn = createCallbackFcn(app, @Button_emitPushed, true);
            app.Button_emit.Text = '发送信号';
            app.Button_channel_off = uibutton(app.UIFigure, 'push');
            app.Button_channel_off.ButtonPushedFcn = createCallbackFcn(app, @Button_channel_offPushed, true);
            app.Button_channel_off.Text = '关闭信道';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end